classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        NumberofvehiclesLabel  matlab.ui.control.Label
        Signal4                matlab.ui.control.Label
        Signal3                matlab.ui.control.Label
        Signal2                matlab.ui.control.Label
        Signal1                matlab.ui.control.Label
        StatusLabel            matlab.ui.control.Label
        EditField4             matlab.ui.control.NumericEditField
        EditField3             matlab.ui.control.NumericEditField
        EditField2             matlab.ui.control.NumericEditField
        EditField1             matlab.ui.control.NumericEditField
        Road4Label             matlab.ui.control.Label
        Road3Label             matlab.ui.control.Label
        Road2Label             matlab.ui.control.Label
        Road1Label             matlab.ui.control.Label
        RunSimulationButton    matlab.ui.control.Button
        UIAxes                 matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: RunSimulationButton
        function RunSimulationButtonPushed(app, event)
          % -------------------------------
% STEP 1: IMAGE-BASED DETECTION
% -------------------------------

img1 = imread('road1.jpg');
img2 = imread('road2.jpg');
img3 = imread('road3.jpg');
img4 = imread('road4.jpg');

% ---------- Road 1 ----------
gray1 = rgb2gray(img1);
bw1 = imbinarize(gray1);
bw1 = imcomplement(bw1);
bw1 = bwareaopen(bw1, 500);
[~, v1] = bwlabel(bw1);

imshow(bw1, 'Parent', app.UIAxes);
title(app.UIAxes, 'Road 1 Detection');
drawnow;
pause(1.5);

% ---------- Road 2 ----------
gray2 = rgb2gray(img2);
bw2 = imbinarize(gray2);
bw2 = imcomplement(bw2);
bw2 = bwareaopen(bw2, 500);
[~, v2] = bwlabel(bw2);

imshow(bw2, 'Parent', app.UIAxes);
title(app.UIAxes, 'Road 2 Detection');
drawnow;
pause(1.5);

% ---------- Road 3 ----------
gray3 = rgb2gray(img3);
bw3 = imbinarize(gray3);
bw3 = imcomplement(bw3);
bw3 = bwareaopen(bw3, 500);
[~, v3] = bwlabel(bw3);

imshow(bw3, 'Parent', app.UIAxes);
title(app.UIAxes, 'Road 3 Detection');
drawnow;
pause(1.5);

% ---------- Road 4 ----------
gray4 = rgb2gray(img4);
bw4 = imbinarize(gray4);
bw4 = imcomplement(bw4);
bw4 = bwareaopen(bw4, 500);
[~, v4] = bwlabel(bw4);

imshow(bw4, 'Parent', app.UIAxes);
title(app.UIAxes, 'Road 4 Detection');
drawnow;
pause(2);

% -------------------------------
% STEP 2: NORMALIZE VALUES (FIX GRAPH)
% -------------------------------

vehicles = [v1 v2 v3 v4];

% Normalize to avoid weird graph
vehicles = max(vehicles, 1);   % avoid zero
vehicles = round(vehicles / 2); % scale down

% -------------------------------
% STEP 3: DISPLAY VALUES
% -------------------------------
app.EditField1.Value = vehicles(1);
app.EditField2.Value = vehicles(2);
app.EditField3.Value = vehicles(3);
app.EditField4.Value = vehicles(4);

% -------------------------------
% STEP 4: GRAPH (CLEAN DISPLAY)
% -------------------------------
cla(app.UIAxes);
bar(app.UIAxes, vehicles);

title(app.UIAxes, 'Traffic Density');
xlabel(app.UIAxes, 'Road Number');
ylabel(app.UIAxes, 'Vehicles');

ylim(app.UIAxes, [0 max(vehicles)+5]); % FIX scaling
drawnow;

% -------------------------------
% STEP 5: TRAFFIC CONTROL LOGIC
% -------------------------------
[sortedVehicles, order] = sort(vehicles, 'descend');

for i = 1:4
    
    road = order(i);
    
    % Reset all signals
    app.Signal1.Text = 'RED'; app.Signal1.FontColor = [1 0 0];
    app.Signal2.Text = 'RED'; app.Signal2.FontColor = [1 0 0];
    app.Signal3.Text = 'RED'; app.Signal3.FontColor = [1 0 0];
    app.Signal4.Text = 'RED'; app.Signal4.FontColor = [1 0 0];
    
    % --------------------
    % YELLOW
    % --------------------
    app.StatusLabel.Text = ['Road ', num2str(road), ' YELLOW'];
    
    if road == 1
        app.Signal1.Text = 'YELLOW'; app.Signal1.FontColor = [1 1 0];
    elseif road == 2
        app.Signal2.Text = 'YELLOW'; app.Signal2.FontColor = [1 1 0];
    elseif road == 3
        app.Signal3.Text = 'YELLOW'; app.Signal3.FontColor = [1 1 0];
    elseif road == 4
        app.Signal4.Text = 'YELLOW'; app.Signal4.FontColor = [1 1 0];
    end
    
    drawnow;
    pause(1);
    
    % --------------------
    % GREEN
    % --------------------
    green_time = 3 + round(sortedVehicles(i)/5);
    
    for t = green_time:-1:1
        
        app.StatusLabel.Text = ['Road ', num2str(road), ...
            ' GREEN | Time: ', num2str(t)];
        
        if road == 1
            app.Signal1.Text = 'GREEN'; app.Signal1.FontColor = [0 1 0];
        elseif road == 2
            app.Signal2.Text = 'GREEN'; app.Signal2.FontColor = [0 1 0];
        elseif road == 3
            app.Signal3.Text = 'GREEN'; app.Signal3.FontColor = [0 1 0];
        elseif road == 4
            app.Signal4.Text = 'GREEN'; app.Signal4.FontColor = [0 1 0];
        end
        
        % Reduce vehicles
        if vehicles(road) > 0
            vehicles(road) = max(vehicles(road)-randi([1 3]), 0);
        end
        
        % Update display
        app.EditField1.Value = vehicles(1);
        app.EditField2.Value = vehicles(2);
        app.EditField3.Value = vehicles(3);
        app.EditField4.Value = vehicles(4);
        
        % Update graph
        cla(app.UIAxes);
        bar(app.UIAxes, vehicles);
        ylim(app.UIAxes, [0 max(vehicles)+5]);
        
        drawnow;
        pause(1);
    end
end

app.StatusLabel.Text = 'All Roads Cleared';
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Traffic Density ')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.RunSimulationButton = uibutton(app.UIFigure, 'push');
            app.RunSimulationButton.ButtonPushedFcn = createCallbackFcn(app, @RunSimulationButtonPushed, true);
            app.RunSimulationButton.Text = 'Run Simulation';
            app.Road1Label = uilabel(app.UIFigure);
            app.Road1Label.Text = 'Road1';
            app.Road2Label = uilabel(app.UIFigure);
            app.Road2Label.Text = 'Road2';
            app.Road3Label = uilabel(app.UIFigure);
            app.Road3Label.Text = 'Road3';
            app.Road4Label = uilabel(app.UIFigure);
            app.Road4Label.Text = 'Road4';
            app.EditField1 = uieditfield(app.UIFigure, 'numeric');
            app.EditField2 = uieditfield(app.UIFigure, 'numeric');
            app.EditField3 = uieditfield(app.UIFigure, 'numeric');
            app.EditField4 = uieditfield(app.UIFigure, 'numeric');
            app.StatusLabel = uilabel(app.UIFigure);
            app.StatusLabel.Text = 'Signal: Waiting...';
            app.Signal1 = uilabel(app.UIFigure);
            app.Signal1.Text = 'RED';
            app.Signal2 = uilabel(app.UIFigure);
            app.Signal2.Text = 'RED';
            app.Signal3 = uilabel(app.UIFigure);
            app.Signal3.Text = 'RED';
            app.Signal4 = uilabel(app.UIFigure);
            app.Signal4.Text = 'RED';
            app.NumberofvehiclesLabel = uilabel(app.UIFigure);
            app.NumberofvehiclesLabel.Text = 'Number of vehicles';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end